classdef ManageAccountForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        DoneButton   matlab.ui.control.Button
        AddButton    matlab.ui.control.Button
        EditField    matlab.ui.control.EditField
        ListBox      matlab.ui.control.ListBox
        GridLayout2  matlab.ui.container.GridLayout
        Button_3     matlab.ui.control.Button
        Button_2     matlab.ui.control.Button
        Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        accountFormType;
        accountFormFileName;
        accountFormVariableName;
        orgForms;
        mainApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, accountFormType, accountFormFileName, accountFormVariableName)
            app.mainApp = mainApp;
            app.accountFormType = accountFormType;
            app.accountFormFileName = accountFormFileName;
            app.accountFormVariableName = accountFormVariableName;
            app.UIFigure.Name = sprintf('Manage %ss', app.accountFormType);
            [isValidFormFile, msg] = ManageAccountFormAction('Check', '', '', app.accountFormFileName, app.accountFormVariableName);
            if ~isValidFormFile
                disp('Check account_forms.mat file!');
                disp(msg);
                return;
            end

            app.orgForms = load(app.accountFormFileName);            
            app.ListBox.Items = app.orgForms.(app.accountFormVariableName).(app.accountFormType);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if isempty(app.EditField.Value)
                return;
            end

            if strcmpi(app.accountFormType, 'Affiliation')
                [isValid, msg] = ValidateAffiliation(app.EditField.Value);
            elseif strcmpi(app.accountFormType, 'EmailDomain')
                [isValid, msg] = ValidateEmailDomain(app.EditField.Value);
            end

            if ~isValid
                disp(msg);
                return;
            end

            if sum(strcmpi(app.ListBox.Items, app.EditField.Value))
                disp('An item that exists in an existing list');
                return;
            end

            app.ListBox.Items{end+1} = app.EditField.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isequal(app.ListBox.Items(:), app.orgForms.(app.accountFormVariableName).(app.accountFormType)(:))
                msg = 'Save changes?';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure, msg, title, ...
                    'Options', {'Yes', 'No', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 3);
                switch selection
                    case 'Yes'
                        newForms = app.orgForms;
                        newForms.(app.accountFormVariableName).(app.accountFormType) = app.ListBox.Items(:);
                        save(app.accountFormFileName, '-struct', 'newForms', app.accountFormVariableName);
                        UpdateAccountForms(app.mainApp);
                    case 'Cancel'
                        return;
                end
            end

            delete(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            orderMat = 1:length(app.ListBox.Items);
            selectedOrder = find(strcmp(app.ListBox.Items, app.ListBox.Value));
            if selectedOrder == 1
                return;
            end

            orderMat(selectedOrder) = orderMat(selectedOrder) - 1;
            orderMat(selectedOrder-1) = orderMat(selectedOrder) + 1;
            app.ListBox.Items = app.ListBox.Items(orderMat);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            orderMat = 1:length(app.ListBox.Items);
            selectedOrder = find(strcmp(app.ListBox.Items, app.ListBox.Value));
            if selectedOrder == orderMat(end)
                return;
            end

            orderMat(selectedOrder) = orderMat(selectedOrder) + 1;
            orderMat(selectedOrder+1) = orderMat(selectedOrder) - 1;
            app.ListBox.Items = app.ListBox.Items(orderMat);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if isempty(app.ListBox.Items) || isempty(app.ListBox.Value)
                return;
            end

            app.ListBox.Items = app.ListBox.Items(~strcmp(app.ListBox.Items, app.ListBox.Value));
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            UIFigureCloseRequest(app, []);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '▲';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '▼';
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
            app.ListBox = uilistbox(app.GridLayout);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Add item';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccountForm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end